classdef CreateAcct < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CreateYourAccountLabel       matlab.ui.control.Label
        EnterYourFirstNameEditFieldLabel  matlab.ui.control.Label
        EnterYourFirstNameEditField  matlab.ui.control.EditField
        UploadImageButton            matlab.ui.control.Button
        UploadImageLabel             matlab.ui.control.Label
        EmbeddedCreateAccountButton  matlab.ui.control.Button
        CreateAccountImageFilepath   matlab.ui.control.Label
    end

    methods (Access = private)

        % Button pushed function: UploadImageButton
        function UploadImageButtonPushed(app, event)
            [createImageFile, createImageFilePath] = uigetfile('*.jpg;*.jpeg');
            app.CreateAccountImageFilepath.Text = strcat(createImageFilePath,createImageFile);
        end

        % Button pushed function: EmbeddedCreateAccountButton
        function EmbeddedCreateAccountButtonPushed(app, event)
            newName = app.EnterYourFirstNameEditField.Value;
            disp(newName);
            accountCreatedSuccessfullyMessage = sprintf('Thank you %s! Your account was created successfully!', newName);
            %fprintf(accountCreatedSuccessfullyMessage);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CreateYourAccountLabel = uilabel(app.UIFigure);
            app.CreateYourAccountLabel.Text = 'Create Your Account!';
            app.EnterYourFirstNameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterYourFirstNameEditFieldLabel.Text = 'Enter Your First Name:';
            app.EnterYourFirstNameEditField = uieditfield(app.UIFigure, 'text');
            app.UploadImageButton = uibutton(app.UIFigure, 'push');
            app.UploadImageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadImageButtonPushed, true);
            app.UploadImageButton.Text = 'Browse...';
            app.UploadImageLabel = uilabel(app.UIFigure);
            app.UploadImageLabel.Text = 'Upload an image of yourself!';
            app.EmbeddedCreateAccountButton = uibutton(app.UIFigure, 'push');
            app.EmbeddedCreateAccountButton.ButtonPushedFcn = createCallbackFcn(app, @EmbeddedCreateAccountButtonPushed, true);
            app.EmbeddedCreateAccountButton.Text = 'Create Account!';
            app.CreateAccountImageFilepath = uilabel(app.UIFigure);
            app.CreateAccountImageFilepath.Text = 'No Image Added';
        end
    end

    methods (Access = public)

        % Construct app
        function app = CreateAcct

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end